classdef GUI_HeartRate_Measuring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CleanButton                   matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        ResultsofheartratecalculationLabel  matlab.ui.control.Label
        CalculationofheartrateusingimageprocessingLabel  matlab.ui.control.Label
        PathtotheFolderEditField      matlab.ui.control.EditField
        PathtotheFolderLabel          matlab.ui.control.Label
        bSavedVideoButton             matlab.ui.control.Button
        PathtotheVideoEditField       matlab.ui.control.EditField
        PathtotheVideoLabel           matlab.ui.control.Label
        StdEditField                  matlab.ui.control.EditField
        BPMEditField                  matlab.ui.control.EditField
        BPMLabel                      matlab.ui.control.Label
        Processing                    matlab.ui.control.Button
        FaceCoordinates               matlab.ui.control.EditField
        FaceButton                    matlab.ui.control.Button
        RightCheekCoordinates         matlab.ui.control.EditField
        RightCheekButton              matlab.ui.control.Button
        LeftCheekCoordinates          matlab.ui.control.EditField
        LeftCheekButton               matlab.ui.control.Button
        MouthCoordinates              matlab.ui.control.EditField
        MouthButton                   matlab.ui.control.Button
        SellectionofCoordinatesLabel  matlab.ui.control.Label
        ForeheadCoordinates           matlab.ui.control.EditField
        ForeheadButton                matlab.ui.control.Button
        NameofFolder1aEditField       matlab.ui.control.EditField
        NameofFolderLabel             matlab.ui.control.Label
        aNewVideoButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: aNewVideoButton
        function aNewVideoButtonPushed(app, event)

            A = app.NameofFolder1aEditField.Value;
            if isempty(A)
                waitfor(msgbox(['Missing text in the Name of Folder box. ' ...
                    'Please write the name so that the folder ' ...
                    'can be saved.'],"Error","error")); % message window
                return
            else
                isequal(A,0)
            end

            waitfor(msgbox("Please choose a folder for save images of video", ...
                'Instruction',"help")); % message window

                folderPath = uigetdir(); %vybrání složky
                mkdir(fullfile(folderPath,A));
                app.PathtotheFolderEditField.Value=fullfile(folderPath,A);

            waitfor(msgbox("The operation has been successfully completed", ...
                "Success",'help')); % message window
            
            waitfor(msgbox("Choose a video for processing",'Instruction',"help"));
          
            [videoName,videoPath] = uigetfile('*.mp4','Select Video');      % Selection of video
            filename = [videoPath videoName];                               % saving the path

            app.PathtotheVideoEditField.Value=filename;
            
            %upozornění
            h = msgbox("This will take few second, please wait","Info","help"); % message window

            video1=VideoReader(filename); 
            nof=video1.NumFrames;                                           %complex number of frames
            Tc=2;                                                           %sampling rate

            
            f=waitbar(0,'Loading your data','Name','Saving images');

                    for ii=1:Tc:nof
                        frames=read(video1,ii);
                        cesta = fullfile(folderPath, A,[[A,'_'], int2str(ii),'.jpg']);
                        imwrite(frames,cesta)

                        waitbar(ii/nof, f, sprintf('Progress: %d %%', floor(ii/nof*100)));
                    end

            delete(f);
            delete(h);         

            waitfor(msgbox("Now continue to the sellection of coordinates." ...
                ,"Success",'help')); % message window
        end

        % Callback function
        function NameOfVideoValueChanged(app, event)

        end

        % Callback function
        function NameOfVideoValueChanging(app, event)

        end

        % Callback function
        function createButtonPushed(app, event)

        end

        % Value changed function: NameofFolder1aEditField
        function NameofFolder1aEditFieldValueChanged(app, event)
         
        end

        % Button pushed function: ForeheadButton
        function ForeheadButtonPushed(app, event)
            [filepath,name]=fileparts(app.PathtotheFolderEditField.Value); %selection of folder
            
            I=imread(fullfile(filepath, name,[name '_', int2str(1),'.jpg']));
            
            uiwait(msgbox(['To select the region of interest, press the left button and ' ...
                'swipe over the image.Double-click in ROI to complete the ' ...
                'selection.'],'Instruction','help')); % message window

            figure('Name','Coordinates for video heart rate processing','NumberTitle','off');
            hold on; title('Selection of Region of Interest - Forehead')
            [~,ROI] = imcrop(I);                %save coordinates of left upper corner and right lower corner"

            app.ForeheadCoordinates.Value=num2str(ROI);

            close all
        end

        % Value changed function: ForeheadCoordinates
        function ForeheadCoordinatesValueChanged(app, event)
                      
        end

        % Button pushed function: MouthButton
        function MouthButtonPushed(app, event)
            [filepath,name]=fileparts(app.PathtotheFolderEditField.Value); %selection of folder

            I=imread(fullfile(filepath, name,[name '_', int2str(1),'.jpg']));
            
            uiwait(msgbox(['To select the region of interest, press the left ' ...
                'button and swipe over the image.Double-click in ROI to complete' ...
                ' the selection.'],'Instruction','help')); % message window

            figure('Name','Coordinates for video heart rate processing','NumberTitle','off');
            hold on; title('Selection of Region of Interest - Mouth')
            [~,ROI] = imcrop(I);                %save coordinates of left upper corner and right lower corner"

            app.MouthCoordinates.Value=num2str(ROI);

            close all 
        end

        % Button pushed function: LeftCheekButton
        function LeftCheekButtonPushed(app, event)
            [filepath,name]=fileparts(app.PathtotheFolderEditField.Value); %selection of folder

            I=imread(fullfile(filepath, name,[name '_', int2str(1),'.jpg']));
            
            uiwait(msgbox(['To select the region of interest, press the ' ...
                'left button and swipe over the image.Double-click in ROI to complete' ...
                ' the selection.'],'Instruction','help')); % message window

            figure('Name','Coordinates for video heart rate processing','NumberTitle','off');
            hold on; title('Selection of Region of Interest - Left Cheek')
            [~,ROI] = imcrop(I);                %save coordinates of left upper corner and right lower corner"

            app.LeftCheekCoordinates.Value=num2str(ROI);

            close all            
        end

        % Button pushed function: RightCheekButton
        function RightCheekButtonPushed(app, event)
            [filepath,name]=fileparts(app.PathtotheFolderEditField.Value); %selection of folder

            I=imread(fullfile(filepath, name,[name '_', int2str(1),'.jpg']));
            
            uiwait(msgbox(['To select the region of interest, press the' ...
                ' left button and swipe over the image.Double-click in ROI to ' ...
                'complete the selection.'],'Instruction','help')); % message window

            figure('Name','Coordinates for video heart rate processing','NumberTitle','off');
            hold on; title('Selection of Region of Interest - Right Cheek')
            [~,ROI] = imcrop(I);            %save coordinates of left upper corner and right lower corner"

            app.RightCheekCoordinates.Value=num2str(ROI);

            close all            
        end

        % Button pushed function: FaceButton
        function FaceButtonPushed(app, event)
                [filepath,name]=fileparts(app.PathtotheFolderEditField.Value); %selection of folder
    
                I=imread(fullfile(filepath, name,[name '_', int2str(1),'.jpg']));
                
                uiwait(msgbox(['To select the region of interest, press ' ...
                    'the left button and swipe over the image.Double-click in ROI ' ...
                    'to complete the selection.'],'Instruction','help')); % message window
    
                figure('Name','Coordinates for video heart rate processing','NumberTitle','off');
                hold on; title('Selection of Region of Interest - Face')
                [~,ROI] = imcrop(I);                %save coordinates of left upper corner and right lower corner"
    
                app.FaceCoordinates.Value=num2str(ROI);
    
                close all            
        end

        % Button pushed function: Processing
        function ProcessingPushed(app, event)
    
    waitfor(msgbox('The heart rate evaluation will take some time, please wait.', ...
        'Info','help')) % message window
    
        wb = waitbar(0, 'Processing ...','WindowStyle', 'modal');
        wbch = allchild(wb);
        jp = wbch(1).JavaPeer;
        jp.setIndeterminate(1);
    
        %% Data Preprocessing  
    ROIForehead=str2double(regexp(app.ForeheadCoordinates.Value,'[\d.]+','match'));
    ROIMouth=str2double(regexp(app.MouthCoordinates.Value,'[\d.]+','match'));
    ROIRightCheek=str2double(regexp(app.RightCheekCoordinates.Value,'[\d.]+','match'));
    ROILeftCheek=str2double(regexp(app.LeftCheekCoordinates.Value,'[\d.]+','match'));
    ROIFace=str2double(regexp(app.FaceCoordinates.Value,'[\d.]+','match'));
    
    VIDEO=VideoReader(app.PathtotheVideoEditField.Value);
    [ImagePath,ImageName]=fileparts(app.PathtotheFolderEditField.Value);        %selection of folder
    
    nof=VIDEO.NumFrames;                                                        %complex number of saved frames
    Tc=2;                                                                       %sampling rate
    
    signalForehead=[];
     for m=1:Tc:nof
        I=imgaussfilt(imread(fullfile(ImagePath, ImageName, ...
            [ImageName '_', num2str(m),'.jpg'])),2);            %blurring the figure with 2D Gaussian filter
        Iout = imcrop(I,ROIForehead);                           %load coordinates to the figure an select ROI
        Gout=Iout(:,:,2);                                       %green signal
        signalForehead =[signalForehead mean(Gout(:))]; 
     end 
    
    signalMouth=[];
     for m=1:Tc:nof
        I=imgaussfilt(imread(fullfile(ImagePath, ImageName, ...
            [ImageName '_', num2str(m),'.jpg'])),2);            %blurring the figure with 2D Gaussian filter
        Iout = imcrop(I,ROIMouth);                              %load coordinates to the figure an select ROI
        Gout=Iout(:,:,2);                                       %green signal   
        signalMouth=[signalMouth mean(Gout(:))]; 
     end 
    
    signalRightCheek=[];
     for m=1:Tc:nof
        I=imgaussfilt(imread(fullfile(ImagePath, ImageName, ...
            [ImageName '_', num2str(m),'.jpg'])),2);            %blurring the figure with 2D Gaussian filter
        Iout = imcrop(I,ROIRightCheek);                         %load coordinates to the figure an select ROI
        Gout=Iout(:,:,2);                                       %green signal
        signalRightCheek =[signalRightCheek mean(Gout(:))];
     end 
    
    signalLeftCheek=[];
     for m=1:Tc:nof
        I=imgaussfilt(imread(fullfile(ImagePath, ImageName, ...
            [ImageName '_', num2str(m),'.jpg'])),2);            %blurring the figure with 2D Gaussian filter
        Iout = imcrop(I,ROILeftCheek);                          %load coordinates to the figure an select ROI
        Gout=Iout(:,:,2);                                       %green signal   
        signalLeftCheek =[signalLeftCheek mean(Gout(:))]; 
     end 
    
    signalFace=[];
     for m=1:Tc:nof
        I=imgaussfilt(imread(fullfile(ImagePath, ImageName, ...
            [ImageName '_', num2str(m),'.jpg'])),2);            %blurring the figure with 2D Gaussian filter
        Iout = imcrop(I,ROIFace);                               %load coordinates to the figure an select ROI  
        Gout=Iout(:,:,2);                                       %green signal
        signalFace =[signalFace mean(Gout(:))]; 
     end 
    
    %% High-pass filter
    HPfreq=0.075;                                                               % Hz Equivalent to 45 BPM
    
    HPForehead=highpass((signalForehead-mean(signalForehead)),HPfreq);          % Forehead                   
    HPMouth=highpass((signalMouth-mean(signalMouth)),HPfreq);                   % Mouth
    HPLeftCheek=highpass((signalLeftCheek-mean(signalLeftCheek)),HPfreq);       % Left Cheek
    HPRightCheek=highpass((signalRightCheek-mean(signalRightCheek)),HPfreq);    % Right Cheek
    HPFace=highpass((signalFace-mean(signalFace)),HPfreq);                      % Face
    
    %% Low-pass filter 
    LPfreq=0.1667;                                                              % Hz Equivalent to 100 BPM
    
    LPForehead=lowpass(HPForehead,LPfreq);                                      % Forehead
    LPMouth=lowpass(HPMouth,LPfreq);                                            % Mouth
    LPLeftCheek=lowpass(HPLeftCheek,LPfreq);                                    % Left Cheek
    LPRightCheek=lowpass(HPRightCheek,LPfreq);                                  % Right Cheek
    LPFace=lowpass(HPFace,LPfreq);                                              % Face
    
    %% Calculation of peaks
    
    PeakDistance=8;                                                             % Minimum distance of peaks
    
    %Calculation of number of peaks in each ROI
    PeakForehead=numel(findpeaks(LPForehead,'MinPeakDistance',PeakDistance));           
    PeakMouth=numel(findpeaks(LPMouth,'MinPeakDistance',PeakDistance));             
    PeakLeftCheek=numel(findpeaks(LPLeftCheek,'MinPeakDistance',PeakDistance));           
    PeakRightCheek=numel(findpeaks(LPRightCheek,'MinPeakDistance',PeakDistance));              
    PeakFace=numel(findpeaks(LPFace,'MinPeakDistance',PeakDistance));    
    
    PeakData=[PeakFace; PeakForehead; PeakLeftCheek; PeakRightCheek; PeakMouth;];
    
    Peaks=mean(PeakData);
    app.BPMEditField.Value=num2str(round((Peaks/(nof/30))*60,1));
    app.StdEditField.Value=num2str(round(std(PeakData),1));
    
    close(wb)

        end

        % Value changed function: BPMEditField
        function BPMEditFieldValueChanged(app, event)

        end

        % Value changed function: StdEditField
        function StdEditFieldValueChanged(app, event)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
 
            
        end

        % Value changed function: PathtotheVideoEditField
        function PathtotheVideoEditFieldValueChanged(app, event)
       
        end

        % Value changed function: PathtotheFolderEditField
        function PathtotheFolderEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: bSavedVideoButton
        function bSavedVideoButtonPushed(app, event)
            waitfor(msgbox("Selecting a video for processing"));
          
            [videoName,videoPath] = uigetfile('*.mp4','Select Video');      % Selection of video
            filename = [videoPath videoName];                               % Saving the path fo the video

            app.PathtotheVideoEditField.Value=filename;


            waitfor(msgbox("Please select the video you want to process."));

            [ImagePath,ImageName] = fileparts(uigetdir('','Selecting a folder for processing'));

            app.PathtotheFolderEditField.Value=fullfile(ImagePath,ImageName);

            waitfor(msgbox("Now continue to the sellection of coordinates.","Success",'help'));
        end

        % Callback function
        function POKUSCELOValueChanged(app, event)

        end

        % Button pushed function: CleanButton
        function CleanButtonButtonPushed(app, event)
            app.NameofFolder1aEditField.Value='';
            app.PathtotheVideoEditField.Value='';
            app.PathtotheFolderEditField.Value='';

            app.ForeheadCoordinates.Value='';
            app.MouthCoordinates.Value='';
            app.LeftCheekCoordinates.Value='';
            app.RightCheekCoordinates.Value='';
            app.FaceCoordinates.Value='';

            app.BPMEditField.Value='';
            app.StdEditField.Value='';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.aNewVideoButton = uibutton(app.UIFigure, 'push');
            app.aNewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @aNewVideoButtonPushed, true);
            app.aNewVideoButton.Tag = 'LoadVideo';
            app.aNewVideoButton.Text = '(1a) New Video';
            app.NameofFolderLabel = uilabel(app.UIFigure);
            app.NameofFolderLabel.Text = 'Name of Folder (1a):';
            app.NameofFolder1aEditField = uieditfield(app.UIFigure, 'text');
            app.NameofFolder1aEditField.ValueChangedFcn = createCallbackFcn(app, @NameofFolder1aEditFieldValueChanged, true);
            app.ForeheadButton = uibutton(app.UIFigure, 'push');
            app.ForeheadButton.ButtonPushedFcn = createCallbackFcn(app, @ForeheadButtonPushed, true);
            app.ForeheadButton.Text = ' (2)   Forehead';
            app.ForeheadCoordinates = uieditfield(app.UIFigure, 'text');
            app.ForeheadCoordinates.ValueChangedFcn = createCallbackFcn(app, @ForeheadCoordinatesValueChanged, true);
            app.SellectionofCoordinatesLabel = uilabel(app.UIFigure);
            app.SellectionofCoordinatesLabel.Text = 'Sellection of Coordinates';
            app.MouthButton = uibutton(app.UIFigure, 'push');
            app.MouthButton.ButtonPushedFcn = createCallbackFcn(app, @MouthButtonPushed, true);
            app.MouthButton.Text = ' (3)   Mouth';
            app.MouthCoordinates = uieditfield(app.UIFigure, 'text');
            app.LeftCheekButton = uibutton(app.UIFigure, 'push');
            app.LeftCheekButton.ButtonPushedFcn = createCallbackFcn(app, @LeftCheekButtonPushed, true);
            app.LeftCheekButton.Text = {' (4)  Left  Cheek'; ''};
            app.LeftCheekCoordinates = uieditfield(app.UIFigure, 'text');
            app.RightCheekButton = uibutton(app.UIFigure, 'push');
            app.RightCheekButton.ButtonPushedFcn = createCallbackFcn(app, @RightCheekButtonPushed, true);
            app.RightCheekButton.Text = ' (5)  Right Cheek';
            app.RightCheekCoordinates = uieditfield(app.UIFigure, 'text');
            app.FaceButton = uibutton(app.UIFigure, 'push');
            app.FaceButton.ButtonPushedFcn = createCallbackFcn(app, @FaceButtonPushed, true);
            app.FaceButton.Text = ' (6)  Face';
            app.FaceCoordinates = uieditfield(app.UIFigure, 'text');
            app.Processing = uibutton(app.UIFigure, 'push');
            app.Processing.ButtonPushedFcn = createCallbackFcn(app, @ProcessingPushed, true);
            app.Processing.Text = {'(7) Process'; ''};
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM :';
            app.BPMEditField = uieditfield(app.UIFigure, 'text');
            app.BPMEditField.ValueChangedFcn = createCallbackFcn(app, @BPMEditFieldValueChanged, true);
            app.StdEditField = uieditfield(app.UIFigure, 'text');
            app.StdEditField.ValueChangedFcn = createCallbackFcn(app, @StdEditFieldValueChanged, true);
            app.PathtotheVideoLabel = uilabel(app.UIFigure);
            app.PathtotheVideoLabel.Text = 'Path to the Video :';
            app.PathtotheVideoEditField = uieditfield(app.UIFigure, 'text');
            app.PathtotheVideoEditField.ValueChangedFcn = createCallbackFcn(app, @PathtotheVideoEditFieldValueChanged, true);
            app.bSavedVideoButton = uibutton(app.UIFigure, 'push');
            app.bSavedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @bSavedVideoButtonPushed, true);
            app.bSavedVideoButton.Text = {'(1b) Saved Video'; ''};
            app.PathtotheFolderLabel = uilabel(app.UIFigure);
            app.PathtotheFolderLabel.Text = {'Path to the Folder :'; ''};
            app.PathtotheFolderEditField = uieditfield(app.UIFigure, 'text');
            app.PathtotheFolderEditField.ValueChangedFcn = createCallbackFcn(app, @PathtotheFolderEditFieldValueChanged, true);
            app.CalculationofheartrateusingimageprocessingLabel = uilabel(app.UIFigure);
            app.CalculationofheartrateusingimageprocessingLabel.Text = 'Calculation of heart rate using image processing.';
            app.ResultsofheartratecalculationLabel = uilabel(app.UIFigure);
            app.ResultsofheartratecalculationLabel.Text = 'Results of heart rate calculation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '±';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonButtonPushed, true);
            app.CleanButton.Text = {'(8) Clean All'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_HeartRate_Measuring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end